classdef Pineapple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        Controls                   matlab.ui.container.Panel
        ColourMapSlider            matlab.ui.control.Slider
        FrequencyEditFieldLabel    matlab.ui.control.Label
        FrequencyEditField         matlab.ui.control.NumericEditField
        VariableEditFieldLabel     matlab.ui.control.Label
        VariableEditField          matlab.ui.control.NumericEditField
        ImportFileButton           matlab.ui.control.Button
        ClearPlotButton            matlab.ui.control.Button
        RegenerateButton           matlab.ui.control.Button
        SlicePlanesButton          matlab.ui.control.Button
        ImpedanceEditFieldLabel    matlab.ui.control.Label
        ImpedanceEditField         matlab.ui.control.NumericEditField
        SliceHeightEditFieldLabel  matlab.ui.control.Label
        SliceHeightEditField       matlab.ui.control.NumericEditField
        VariableNameLabel          matlab.ui.control.Label
        VariableNameEditField      matlab.ui.control.EditField
        PlotTitleEditFieldLabel    matlab.ui.control.Label
        PlotTitleEditField         matlab.ui.control.EditField
        Plot2DButton               matlab.ui.control.Button
        Log2DPlotsCheckBox         matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        x = 1;
        y = 2;
        z = 3;
        xx = 4;
        yy = 5;
        zz = 6;
        T = 7;
        zSlice = 50;
        fSlice = 100;
        lSlice = 5;
        intercept = 10;
        sliceHeight = 1000;
        i = 0;
        variableName = 'Variable';
        plotTitle = 'Plot Title';
        fArray = [];
        vArray = [];
        customColormap=    [0         0    0.5333
            0         0    0.7667
            0         0    1.0000
            0    0.1250    1.0000
            0    0.2500    1.0000
            0    0.3750    1.0000
            0    0.5000    1.0000
            0    0.6250    1.0000
            0    0.7500    1.0000
            0    0.8750    1.0000
            0    1.0000    1.0000
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            0.5333         0         0];
    end
    
    methods (Access = private)
        
        function [x,y,z] = C2xyz(C)
% C2XYZ returns the x and y coordinates of contours in a contour
% matrix and their corresponding z values. C is the contour matrix given by 
% the contour function. 
% 
%
%% Syntax
% 
%  [x,y] = C2xyz(C)
%  [x,y,z] = C2xyz(C)
% 
%% Description 
% 
% [x,y] = C2xyz(C) returns x and y coordinates of contours in a contour
% matrix C
% 
% [x,y,z] = C2xyz(C) also returns corresponding z values. 
% 
% 
%% Example
% Given a contour plot, you want to know the (x,y) coordinates of the contours, 
% as well as the z value corresponding to each contour line. 
%
% C = contour(peaks); 
% [x,y,z] = C2xyz(C);
% 
% This returns 1 x numberOfContourLines cells of x values and y values, and
% their corresponding z values are given in a 1 x numberOfContourLines
% array. If you'd like to plot a heavy black line along all of the z=0
% contours and a dotted red line along the z = -2 contours, try this: 
% 
% hold on; % Allows plotting atop the preexisting peaks plot. 
% for n = find(z==0); % only loop through the z = 0 values. 
%     plot(x{n},y{n},'k','linewidth',2)
% end
% 
% for n = find(z==-2) % now loop through the z = -2 values. 
%     plot(x{n},y{n},'r:','linewidth',2)
% end
% 
% * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * *
% Created by Chad Greene, August 2013. 
% 
% * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * * *
% See also contour, contourf, clabel, contour3, and C2xy.
m(1)=1; 
n=1;  
try
    while n<length(C)
        n=n+1;
        m(n) = m(n-1)+C(2,m(n-1))+1; 
        
    end
end
for nn = 1:n-2
     x{nn} = C(1,m(nn)+1:m(nn+1)-1); 
     y{nn} = C(2,m(nn)+1:m(nn+1)-1); 
     if nargout==3
        z(nn) = C(1,m(nn));
     end
end
end
        
        function generate3D(app,t)
            
            app.x = table2array(t(:,1:1));
            app.y = table2array(t(:,2:2));
            app.z = table2array(t(:,3:3));
            
            [app.xx,app.yy]=meshgrid(0:0.9:(max(app.x)),0:1.25:(max(app.y)));
            app.zz = griddata(app.x,app.y,app.z,app.xx,app.yy);
            s = surf(app.UIAxes, app.xx,app.yy,app.zz);
            shading(app.UIAxes, 'interp');
            c = colorbar(app.UIAxes);
            lighting(app.UIAxes, 'gouraud');
            lightangle(app.UIAxes, 40,30);
            rotate3d(app.UIAxes,'on');
            colormap(app.UIAxes,app.customColormap);
            app.sliceHeight = (0.5*(max(app.z)));
            app.UIAxes.CLim = [0 950];
            
            app.UIAxes.XLabel.String = 'Frequency (MHz)';
            app.UIAxes.YLabel.String = app.variableName;
            app.UIAxes.ZLabel.String = 'Impedance (Ohms)';
            title(app.UIAxes,app.plotTitle);
        end
        
        function generateDiet3D(app,XX,YY,ZZ)
            s = surf(app.UIAxes,XX,YY,ZZ);
            lighting(app.UIAxes, 'gouraud');
            shading(app.UIAxes, 'interp');
            lightangle(app.UIAxes, 40,30);
        end
        
        function patchZ(app,zSlice)
            patch(app.UIAxes,[(min(app.x)+1),max(app.x),max(app.x),(min(app.x)+1)],[(min(app.y)+1),(min(app.y)+1),max(app.y),max(app.y)],[zSlice,zSlice,zSlice,zSlice],[0.5 1 0.5],'FaceAlpha',0.5);
        end
        
        function patchX(app,xSlice)
            app.intercept = interp2(app.xx,app.yy,app.zz,app.fSlice,app.lSlice);
            patch(app.UIAxes,[xSlice,xSlice,xSlice,xSlice],[(min(app.y)+1),(min(app.y)+1),max(app.y),max(app.y)],[app.sliceHeight,(min(app.z)+1),(min(app.z)+1),app.sliceHeight],[0.5 1 0.5],'FaceAlpha',0.5);
        end
        
        function patchY(app,ySlice)
            app.intercept = interp2(app.xx,app.yy,app.zz,app.fSlice,app.lSlice);
            patch(app.UIAxes,[(min(app.x)+1),(min(app.x)+1),max(app.x),max(app.x)],[ySlice,ySlice,ySlice,ySlice],[app.sliceHeight,1,1,app.sliceHeight],[0.5 1 0.5],'FaceAlpha',0.5);
        end
        
        function generate2D(app,xSlice,ySlice)
            [M1,c1] = contour(app.UIAxes2,app.xx,app.zz,app.yy,[ySlice ySlice]);
            [M2,c2] = contour(app.UIAxes3,app.zz,app.yy,app.xx,[xSlice xSlice]);
            if isequal(app.i,0)
                camroll(app.UIAxes3,90);
                app.i = app.i + 1;
            end
            c1.LineWidth = 1.4;
            c2.LineWidth = 1.4;
            clabel([],c1,'FontSize',8,'Color','red','Rotation',0,'Margin',0.1,'LabelSpacing',300);
            clabel([],c2,'FontSize',8,'Color','red','Rotation',0,'Margin',0.1,'LabelSpacing',300);
            hold(app.UIAxes3,'on');
            hold(app.UIAxes2,'on');
            app.UIAxes.YLabel.String = app.variableName;
            app.UIAxes2.XLabel.String = 'Frequency (MHz)';
            app.UIAxes2.YLabel.String = 'Impedance ({\Omega})';
            app.UIAxes3.YLabel.String = app.variableName;
            app.UIAxes3.XLabel.String = 'Impedance ({\Omega})';
            
        end
        
        
        function fArrayAdd(app,V)
            if ismember(V,app.fArray) == 0
                app.fArray = [app.fArray, V];
                disp(app.fArray);
            else
                disp(V + "MHz is already a member of the frequency array, not adding");
            end
        end
        
        function vArrayAdd(app,V)
            if ismember(V,app.vArray) == 0
                app.vArray = [app.vArray, V];
                disp(app.vArray);
            else
                disp(V + " is already a member of the " + app.variableName + " array, not adding");
            end            
        end
        
        function update2DTitles(app,F,V)
            
            FString = sprintf('%.0f,',F);
            FString = FString(1:end-1);
            VString = sprintf('%.0f,',V);
            VString = VString(1:end-1);
            
            title(app.UIAxes3,{"Impedance vs " + app.variableName + " at Frequency (MHz):",FString});
            title(app.UIAxes2,{"Impedance vs Frequency at " + app.variableName + ":",VString});
            
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                app.T = readtable(fullfile(path,file));
                generate3D(app, app.T);
            end
        end

        % Value changed function: ColourMapSlider
        function ColourMapSliderValueChanged(app, event)
            sliderCLim = app.ColourMapSlider.Value;
            app.UIAxes.CLim = [0 sliderCLim];
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.fArray = [];
            app.vArray = [];
            update2DTitles(app,app.fArray,app.vArray);
        end

        % Button pushed function: RegenerateButton
        function RegenerateButtonPushed(app, event)
            cla(app.UIAxes);
            generateDiet3D(app,app.xx,app.yy,app.zz);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            app.fSlice = app.FrequencyEditField.Value;
        end

        % Value changed function: VariableEditField
        function VariableEditFieldValueChanged(app, event)
            app.lSlice = app.VariableEditField.Value;
        end

        % Value changed function: ImpedanceEditField
        function ImpedanceEditFieldValueChanged(app, event)
            app.zSlice = app.ImpedanceEditField.Value;
        end

        % Button pushed function: SlicePlanesButton
        function SlicePlanesButtonPushed(app, event)
            patchX(app,app.fSlice);
            patchY(app,app.lSlice);
            patchZ(app,app.zSlice);
        end

        % Value changed function: SliceHeightEditField
        function SliceHeightEditFieldValueChanged(app, event)
            app.sliceHeight = app.SliceHeightEditField.Value;
        end

        % Button pushed function: Plot2DButton
        function Plot2DButtonPushed(app, event)
            generate2D(app,app.fSlice,app.lSlice);
            fArrayAdd(app,app.fSlice);
            vArrayAdd(app,app.lSlice);
            update2DTitles(app,app.fArray,app.vArray);
        end

        % Value changed function: VariableNameEditField
        function VariableNameEditFieldValueChanged(app, event)
            app.variableName = app.VariableNameEditField.Value;
            app.VariableEditFieldLabel.Text = app.variableName;
            app.UIAxes.YLabel.String = app.variableName;
            app.UIAxes3.YLabel.String = app.variableName;
            update2DTitles(app,app.fArray,app.vArray);
        end

        % Value changed function: PlotTitleEditField
        function PlotTitleEditFieldValueChanged(app, event)
            app.plotTitle = app.PlotTitleEditField.Value;
            title(app.UIAxes,app.plotTitle);
        end

        % Value changed function: Log2DPlotsCheckBox
        function Log2DPlotsCheckBoxValueChanged(app, event)
            value = app.Log2DPlotsCheckBox.Value;
            if value == 1
            app.UIAxes2.YScale = 'log';
            app.UIAxes3.XScale = 'log';
            else
                app.UIAxes2.YScale = 'linear';
                app.UIAxes3.XScale = 'linear';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.ZScale = 'log';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.YDir = 'reverse';
            app.Controls = uipanel(app.UIFigure);
            app.Controls.Title = 'Controls';
            app.ColourMapSlider = uislider(app.Controls);
            app.ColourMapSlider.Limits = [1 1500];
            app.ColourMapSlider.MajorTickLabels = {'', '', '', '', ''};
            app.ColourMapSlider.ValueChangedFcn = createCallbackFcn(app, @ColourMapSliderValueChanged, true);
            app.ColourMapSlider.Value = 950;
            app.FrequencyEditFieldLabel = uilabel(app.Controls);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.Controls, 'numeric');
            app.FrequencyEditField.Limits = [0.1 Inf];
            app.FrequencyEditField.ValueDisplayFormat = '%11.4g MHz';
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 100;
            app.VariableEditFieldLabel = uilabel(app.Controls);
            app.VariableEditFieldLabel.Text = 'Variable';
            app.VariableEditField = uieditfield(app.Controls, 'numeric');
            app.VariableEditField.ValueChangedFcn = createCallbackFcn(app, @VariableEditFieldValueChanged, true);
            app.VariableEditField.Value = 5;
            app.ImportFileButton = uibutton(app.Controls, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.ClearPlotButton = uibutton(app.Controls, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.RegenerateButton = uibutton(app.Controls, 'push');
            app.RegenerateButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateButtonPushed, true);
            app.RegenerateButton.Text = 'Regenerate';
            app.SlicePlanesButton = uibutton(app.Controls, 'push');
            app.SlicePlanesButton.ButtonPushedFcn = createCallbackFcn(app, @SlicePlanesButtonPushed, true);
            app.SlicePlanesButton.Text = 'Slice Planes';
            app.ImpedanceEditFieldLabel = uilabel(app.Controls);
            app.ImpedanceEditFieldLabel.Text = 'Impedance';
            app.ImpedanceEditField = uieditfield(app.Controls, 'numeric');
            app.ImpedanceEditField.ValueDisplayFormat = '%11.4g Ohms';
            app.ImpedanceEditField.ValueChangedFcn = createCallbackFcn(app, @ImpedanceEditFieldValueChanged, true);
            app.ImpedanceEditField.Value = 50;
            app.SliceHeightEditFieldLabel = uilabel(app.Controls);
            app.SliceHeightEditFieldLabel.Text = 'SliceHeight';
            app.SliceHeightEditField = uieditfield(app.Controls, 'numeric');
            app.SliceHeightEditField.ValueDisplayFormat = '%11.4g Ohms';
            app.SliceHeightEditField.ValueChangedFcn = createCallbackFcn(app, @SliceHeightEditFieldValueChanged, true);
            app.SliceHeightEditField.Value = 1000;
            app.VariableNameLabel = uilabel(app.Controls);
            app.VariableNameLabel.Text = 'Variable Name: ';
            app.VariableNameEditField = uieditfield(app.Controls, 'text');
            app.VariableNameEditField.ValueChangedFcn = createCallbackFcn(app, @VariableNameEditFieldValueChanged, true);
            app.PlotTitleEditFieldLabel = uilabel(app.Controls);
            app.PlotTitleEditFieldLabel.Text = 'Plot Title: ';
            app.PlotTitleEditField = uieditfield(app.Controls, 'text');
            app.PlotTitleEditField.ValueChangedFcn = createCallbackFcn(app, @PlotTitleEditFieldValueChanged, true);
            app.Plot2DButton = uibutton(app.Controls, 'push');
            app.Plot2DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot2DButtonPushed, true);
            app.Plot2DButton.Text = 'Plot 2D';
            app.Log2DPlotsCheckBox = uicheckbox(app.Controls);
            app.Log2DPlotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Log2DPlotsCheckBoxValueChanged, true);
            app.Log2DPlotsCheckBox.Text = 'Log 2D Plots';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Pineapple

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end